classdef run_part_A < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Part_A_Fuzzy_Control_Systems    matlab.ui.Figure
        MandaniPanel                    matlab.ui.container.Panel
        NumberofRulesLabel_2            matlab.ui.control.Label
        NumberofRulesDropDown           matlab.ui.control.DropDown
        DefuzzificationFunctionLabel    matlab.ui.control.Label
        DefuzzificationFunctionDropDown  matlab.ui.control.DropDown
        RunMandaniButton                matlab.ui.control.Button
        PartAFuzzyControlSystemsLabel   matlab.ui.control.Label
        SugenoPanel                     matlab.ui.container.Panel
        NumberofRulesLabel_3            matlab.ui.control.Label
        NumberofRulesDropDown_2         matlab.ui.control.DropDown
        DefuzzificationFunctionLabel_2  matlab.ui.control.Label
        DefuzzificationFunctionDropDown_2  matlab.ui.control.DropDown
        RunSugenoButton                 matlab.ui.control.Button
        SimulinkModelPanel              matlab.ui.container.Panel
        ReferenceDropDownLabel          matlab.ui.control.Label
        ReferenceDropDown               matlab.ui.control.DropDown
        DisturbanceDropDownLabel        matlab.ui.control.Label
        DisturbanceDropDown             matlab.ui.control.DropDown
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunMandaniButton
        function RunMandaniButtonPushed(app, event)
            % Fuzzy Model
            controller_system = 'mandani';
            num_rules = app.NumberofRulesDropDown.Value;
            defuzzification_fcn = app.DefuzzificationFunctionDropDown.Value;
            % Simulink Model
            reference = app.ReferenceDropDown.Value;
            disturbance = app.DisturbanceDropDown.Value;
                        
            % Load Controller to Workspace
            fuzzy_controller_path = ['part-A/fuzzy-controllers/', controller_system, ...
                '_', defuzzification_fcn, '_', num_rules];
            fuzzy_controller = readfis(fuzzy_controller_path);
            % Save variable on workspace
            assignin('base','fis',fuzzy_controller)
            
            % Open Simulink
            model_path = ['part-A/simulink-models/model_', reference, '_'];
            if strcmp(disturbance, 'none')
                disturbance = 'no_disturbance';
            elseif strcmp(disturbance, 'actuator')
                disturbance = 'actuator_disturbance';
            elseif strcmp(disturbance, 'load')
                disturbance = 'load_disturbance';
            end
            model_path = [model_path, disturbance, '.slx'];    
            
            uiopen(model_path, 1);
        end

        % Button pushed function: RunSugenoButton
        function RunSugenoButtonPushed(app, event)
            % Fuzzy Model
            controller_system = 'sugeno';
            num_rules = app.NumberofRulesDropDown_2.Value;
            defuzzification_fcn = app.DefuzzificationFunctionDropDown_2.Value;
            % Simulink Model
            reference = app.ReferenceDropDown.Value;
            disturbance = app.DisturbanceDropDown.Value;
            
            % Load Controller to Workspace
            fuzzy_controller_path = ['part-A/fuzzy-controllers/', controller_system, ...
                '_', defuzzification_fcn, '_', num_rules];
            fuzzy_controller = readfis(fuzzy_controller_path);
            % Save variable on workspace
            assignin('base','fis',fuzzy_controller)
            
            % Open Simulink
            model_path = ['part-A/simulink-models/model_', reference, '_'];
            if strcmp(disturbance, 'none')
                disturbance = 'no_disturbance';
            elseif strcmp(disturbance, 'actuator')
                disturbance = 'actuator_disturbance';
            elseif strcmp(disturbance, 'load')
                disturbance = 'load_disturbance';
            end
            model_path = [model_path, disturbance, '.slx'];    
            
            uiopen(model_path, 1);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Part_A_Fuzzy_Control_Systems = uifigure('Visible', 'off');
            app.Part_A_Fuzzy_Control_Systems.Name = 'Part A: Fuzzy Control Systems';
            app.MandaniPanel = uipanel(app.Part_A_Fuzzy_Control_Systems);
            app.MandaniPanel.Title = 'Mandani';
            app.NumberofRulesLabel_2 = uilabel(app.MandaniPanel);
            app.NumberofRulesLabel_2.Text = {'Number of'; 'Rules'};
            app.NumberofRulesDropDown = uidropdown(app.MandaniPanel);
            app.NumberofRulesDropDown.Items = {'9', '25'};
            app.NumberofRulesDropDown.Value = '9';
            app.DefuzzificationFunctionLabel = uilabel(app.MandaniPanel);
            app.DefuzzificationFunctionLabel.Text = {'Defuzzification'; 'Function'};
            app.DefuzzificationFunctionDropDown = uidropdown(app.MandaniPanel);
            app.DefuzzificationFunctionDropDown.Items = {'centroid', 'mom'};
            app.DefuzzificationFunctionDropDown.Value = 'centroid';
            app.RunMandaniButton = uibutton(app.MandaniPanel, 'push');
            app.RunMandaniButton.ButtonPushedFcn = createCallbackFcn(app, @RunMandaniButtonPushed, true);
            app.RunMandaniButton.Text = 'Run';
            app.PartAFuzzyControlSystemsLabel = uilabel(app.Part_A_Fuzzy_Control_Systems);
            app.PartAFuzzyControlSystemsLabel.Text = {'Part A'; 'Fuzzy Control Systems'};
            app.SugenoPanel = uipanel(app.Part_A_Fuzzy_Control_Systems);
            app.SugenoPanel.Title = 'Sugeno';
            app.NumberofRulesLabel_3 = uilabel(app.SugenoPanel);
            app.NumberofRulesLabel_3.Text = {'Number of'; 'Rules'};
            app.NumberofRulesDropDown_2 = uidropdown(app.SugenoPanel);
            app.NumberofRulesDropDown_2.Items = {'9', '25'};
            app.NumberofRulesDropDown_2.Value = '9';
            app.DefuzzificationFunctionLabel_2 = uilabel(app.SugenoPanel);
            app.DefuzzificationFunctionLabel_2.Text = {'Defuzzification'; 'Function'};
            app.DefuzzificationFunctionDropDown_2 = uidropdown(app.SugenoPanel);
            app.DefuzzificationFunctionDropDown_2.Items = {'wtaver', 'wtsum'};
            app.DefuzzificationFunctionDropDown_2.Value = 'wtaver';
            app.RunSugenoButton = uibutton(app.SugenoPanel, 'push');
            app.RunSugenoButton.ButtonPushedFcn = createCallbackFcn(app, @RunSugenoButtonPushed, true);
            app.RunSugenoButton.Text = 'Run';
            app.SimulinkModelPanel = uipanel(app.Part_A_Fuzzy_Control_Systems);
            app.SimulinkModelPanel.Title = 'Simulink Model';
            app.ReferenceDropDownLabel = uilabel(app.SimulinkModelPanel);
            app.ReferenceDropDownLabel.Text = 'Reference';
            app.ReferenceDropDown = uidropdown(app.SimulinkModelPanel);
            app.ReferenceDropDown.Items = {'sin', 'square', 'sawtooth'};
            app.ReferenceDropDown.Value = 'sin';
            app.DisturbanceDropDownLabel = uilabel(app.SimulinkModelPanel);
            app.DisturbanceDropDownLabel.Text = 'Disturbance';
            app.DisturbanceDropDown = uidropdown(app.SimulinkModelPanel);
            app.DisturbanceDropDown.Items = {'none', 'load', 'actuator'};
            app.DisturbanceDropDown.Value = 'none';
            % Show the figure after all components are created
            app.Part_A_Fuzzy_Control_Systems.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = run_part_A

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Part_A_Fuzzy_Control_Systems)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Part_A_Fuzzy_Control_Systems)
        end
    end
end